classdef Do_an_PPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        THCHNHPHNGPHPTNHLabel          matlab.ui.control.Label
        NLabel                         matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        NghiemTab                      matlab.ui.container.Tab
        ChonphuongphaptimListBox       matlab.ui.control.ListBox
        ChnphngphptmListBoxLabel       matlab.ui.control.Label
        TimnghiemButton                matlab.ui.control.Button
        SolanlapEditField              matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        KetquanghiemEditField          matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel        matlab.ui.control.Label
        NhapkhoangphanlynghiemEditField  matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        NhapphuongtrinhEditField       matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        NhapsaischophepEditField       matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel    matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NoisuyTab                      matlab.ui.container.Tab
        NoisuyButton                   matlab.ui.control.Button
        ChonphuongphapnoisuyListBox    matlab.ui.control.ListBox
        ChnphngphpnisuyListBoxLabel    matlab.ui.control.Label
        KetquanoisuyEditField          matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel        matlab.ui.control.Label
        KetquadathucnoisuyEditField    matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        NhapgiatricannoisuyEditField   matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        NhapdulieuyEditField           matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        NhapdulieuxEditField           matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        HoiQuyTab                      matlab.ui.container.Tab
        HoiquyButton                   matlab.ui.control.Button
        ChonphuongphaphoiquyListBox    matlab.ui.control.ListBox
        ChnphngphphiquyListBoxLabel    matlab.ui.control.Label
        KetquadudoanEditField          matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel          matlab.ui.control.Label
        NhapgiatricandudoanEditField   matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        KetquaphuongtrinhhoiquyEditField  matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        NhapdulieuyEditField_2         matlab.ui.control.EditField
        NhpdliuyEditField_2Label       matlab.ui.control.Label
        NhapdulieuxEditField_2         matlab.ui.control.EditField
        NhpdliuxEditField_2Label       matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        DaohamTab                      matlab.ui.container.Tab
        Panelhamso                     matlab.ui.container.Panel
        NhapbuochEditField             matlab.ui.control.EditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        NhaphamsoEditField             matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        Panelx_y                       matlab.ui.container.Panel
        NhapdulieuyEditField_3         matlab.ui.control.EditField
        NhpdliuyEditField_3Label       matlab.ui.control.Label
        NhapdulieuxEditField_3         matlab.ui.control.EditField
        NhpdliuxEditField_3Label       matlab.ui.control.Label
        ChoncachnhapListBox            matlab.ui.control.ListBox
        ChncchnhpListBoxLabel          matlab.ui.control.Label
        NhapgiatricantinhdaohamEditField_2  matlab.ui.control.EditField
        NhpgitrcntnhohmEditField_2Label  matlab.ui.control.Label
        KetquaEditField                matlab.ui.control.NumericEditField
        KetquaButton                   matlab.ui.control.Button
        ChonphuongphapdaohamListBox    matlab.ui.control.ListBox
        ChnphngphpohmListBoxLabel      matlab.ui.control.Label
        ChongiatrisaisoListBox         matlab.ui.control.ListBox
        ChngitrsaisListBoxLabel        matlab.ui.control.Label
        TichphanTab                    matlab.ui.container.Tab
        Panelx_y_2                     matlab.ui.container.Panel
        NhapdulieuyEditField_4         matlab.ui.control.EditField
        NhpdliuyEditField_4Label       matlab.ui.control.Label
        NhapdulieuxEditField_4         matlab.ui.control.EditField
        NhpdliuxEditField_4Label       matlab.ui.control.Label
        ChoncachnhapListBox_2          matlab.ui.control.ListBox
        ChncchnhpListBoxLabel_2        matlab.ui.control.Label
        Panelhamso_2                   matlab.ui.container.Panel
        NhaphamsoEditField_2           matlab.ui.control.EditField
        NhphmsEditField_2Label         matlab.ui.control.Label
        NhapcanEditField               matlab.ui.control.EditField
        NhpcntnhtchphnEditFieldLabel   matlab.ui.control.Label
        Ketqua_1EditField              matlab.ui.control.NumericEditField
        KetquaButton_2                 matlab.ui.control.Button
        NhapNEditField                 matlab.ui.control.NumericEditField
        NhpNEditFieldLabel             matlab.ui.control.Label
        ChonphuongphaptichphanListBox  matlab.ui.control.ListBox
        ChnphngphptchphnListBoxLabel   matlab.ui.control.Label
        GiithiunhmTab                  matlab.ui.container.Tab
        VaitrcodeLabel                 matlab.ui.control.Label
        MSSV23207017Label              matlab.ui.control.Label
        VcQuangLabel                   matlab.ui.control.Label
        VaitrthitlpgiaodinchnhwordLabel  matlab.ui.control.Label
        MSSV23207023Label              matlab.ui.control.Label
        LNguynThyDngLabel              matlab.ui.control.Label
        VaitrsonwordLabel              matlab.ui.control.Label
        MSSV23207012Label              matlab.ui.control.Label
        PhmMinhNgcLabel                matlab.ui.control.Label
        Lp23DTV_CLC2Label              matlab.ui.control.Label
        GVHDThsHunhQucThnhLabel        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    methods (Access = private)

        function num_arr = clean_and_parse(app, str)
            % Hàm làm sạch dữ liệu nhập vào
            try
                if isempty(str), num_arr = []; return; end
                
                % Thay thế dấu phẩy thành khoảng trắng
                str_clean = strrep(str, ',', ' ');
                % Giữ lại số, dấu chấm, dấu trừ, chữ e (cho số mũ)
                str_clean = regexprep(str_clean, '[^0-9.\-eE]+', ' ');
                
                % Chuyển đổi
                num_arr = str2num(str_clean);
                num_arr = num_arr(:); % Chuyển thành vector cột
            catch
                num_arr = [];
            end
        end

        function f = convert_string_to_function(app, str)
            try
                str = lower(str);
                str = strrep(str, '^', '.^');
                str = strrep(str, '*', '.*');
                str = strrep(str, '/', './');
                f = str2func(['@(x) ' str]);
            catch
                f = [];
            end
        end
            
        function [root, iter] = logic_tim_nghiem(app, f, a, b, tol, method)
            root = NaN; iter = 0; % iter là số lần lặp ban đầu
            switch method
                case 'Chia đôi'
                    if f(a)*f(b) > 0, return; end
                    while (b - a)/2 > tol && iter < 1000
                        c = (a + b) / 2;
                        if f(c) == 0, break; end
                        if f(a)*f(c) < 0, b = c; else, a = c; end
                        iter = iter + 1;
                    end
                    root = (a + b) / 2;
                case 'Lặp'
                    x0 = (a + b) /2;
                    root = x0;
                    err = 1;
                    while err > tol && iter < 1000
                        x_new = f(root);
                        if isinf(x_new) || isnan(x_new)
                            break;
                        end
                        err = abs(x_new - root);
                        root = x_new;
                        iter = iter + 1;
                    end
                case 'Newton (tiếp tuyến)'
                    % Tính đạo hàm xấp xỉ
                    h = 1e-5;
                    df = @(x) (f(x+h) - f(x))/h;
                    x0 = (a+b)/2; root = x0; err = 1;
                    while err > tol && iter < 1000
                        val = f(root); dval = df(root);
                        if abs(dval) < 1e-10, break; end
                        x_new = root - val/dval;
                        err = abs(x_new - root);
                        root = x_new;
                        iter = iter + 1;
                    end
            end
        end
        
        function [result, str_poly] = logic_noi_suy(app, x, y, val, method)
            x = x(:); y = y(:); n = length(x);
            
            % 1. Tính giá trị dự đoán (như cũ)
            result = 0;
            if strcmp(method, 'Lagrange')
                for i = 1:n
                    L = 1;
                    for j = 1:n
                        if i ~= j, L = L * (val - x(j)) / (x(i) - x(j)); end
                    end
                    result = result + y(i) * L;
                end
            else 
                d = zeros(n, n); d(:,1) = y;
                for j = 2:n
                    for i = 1:n-j+1, d(i,j) = (d(i+1,j-1) - d(i,j-1)) / (x(i+j-1) - x(i)); end
                end
                result = d(1,1); pt = 1;
                for j = 1:n-1, pt = pt * (val - x(j)); result = result + d(1,j+1) * pt; end
            end
            
            % 2. Tạo chuỗi đa thức hiển thị (vd: y = 2x^2 + 3x)
            % Sử dụng polyfit để tìm hệ số đa thức
            if n > 1
                % Dùng polyfit để lấy hệ số đa thức (bậc n-1 đi qua n điểm)
                % Chú ý: Với cùng tập dữ liệu, Newton và Lagrange ra cùng 1 đa thức
                p = polyfit(x, y, n-1); 
                
                str_poly = 'y = ';
                for i = 1:length(p)
                    pow = length(p) - i;
                    coef = p(i);
                    
                    if abs(coef) < 1e-5, continue; end % Bỏ qua hệ số quá nhỏ
                    
                    % Xử lý dấu
                    if coef >= 0 && i > 1
                        sign_str = ' + ';
                    elseif coef < 0
                        sign_str = ' - ';
                        coef = abs(coef);
                    else
                        sign_str = '';
                    end
                    
                    % Xử lý x^n
                    if pow == 0
                        term = sprintf('%.4f', coef);
                    elseif pow == 1
                        term = sprintf('%.4f*x', coef);
                    else
                        term = sprintf('%.4f*x^%d', coef, pow);
                    end
                    
                    str_poly = [str_poly, sign_str, term];
                end
                
                if strcmp(str_poly, 'y = '), str_poly = 'y = 0'; end % Trường hợp đa thức 0
            else
                str_poly = sprintf('y = %.4f', y(1));
            end
        end
        
        function [y_pred, eq_str, x_fit, y_fit] = logic_hoi_quy(app, x, y, val_predict, method)
            x = x(:); y = y(:); 
            
            % Tính toán khoảng vẽ đồ thị an toàn
            min_x = min(x); max_x = max(x);
            if isempty(min_x), min_x = 0; max_x = 1; end
            x_fit = linspace(min_x, max_x, 100);
            
            if strcmp(method, 'Tuyến tính')
                p = polyfit(x, y, 1);
                y_pred = polyval(p, val_predict);
                eq_str = sprintf('y = %.4fx + %.4f', p(1), p(2));
                y_fit = polyval(p, x_fit);
            elseif strcmp(method, 'Hàm mũ')
                try
                    p = polyfit(x, log(y), 1);
                    B = p(1); A = exp(p(2));
                    y_pred = A * exp(B * val_predict);
                    eq_str = sprintf('y = %.4f * e^(%.4fx)', A, B);
                    y_fit = A * exp(B * x_fit);
                catch
                    y_pred = NaN; eq_str = 'Lỗi dữ liệu (y<=0)'; x_fit=[]; y_fit=[];
                end
            else 
                try
                    p = polyfit(log(x), y, 1);
                    B = p(1); A = p(2);
                    y_pred = A + B * log(val_predict);
                    eq_str = sprintf('y = %.4f + %.4f*ln(x)', A, B);
                    y_fit = A + B * log(x_fit);
                catch
                    y_pred = NaN; eq_str = 'Lỗi dữ liệu (x<=0)'; x_fit=[]; y_fit=[];
                end
            end
        end

        
function res = logic_dao_ham_data(app, x, y, x0, method)
            res = NaN;
            % Tìm vị trí của x0 trong mảng x (so sánh xấp xỉ)
            idx = find(abs(x - x0) < 1e-9, 1); 
            
            if isempty(idx)
                error('Giá trị X cần tính đạo hàm không có trong dữ liệu nhập vào.');
            end
            
            n = length(x);
            
            if strcmp(method, 'Xấp xỉ tiến')
                if idx < n
                    res = (y(idx+1) - y(idx)) / (x(idx+1) - x(idx));
                else
                    error('Không thể tính Xấp xỉ Tiến tại điểm cuối cùng (chỉ số vượt quá mảng).');
                end
            elseif strcmp(method, 'Lùi')
                if idx > 1
                    res = (y(idx) - y(idx-1)) / (x(idx) - x(idx-1));
                else
                    error('Không thể tính Xấp xỉ Lùi tại điểm đầu tiên (chỉ số bằng 0).');
                end
            else % Trung tâm
                if idx > 1 && idx < n
                    res = (y(idx+1) - y(idx-1)) / (x(idx+1) - x(idx-1));
                else
                    error('Không thể tính Xấp xỉ Trung tâm tại điểm đầu hoặc cuối.');
                end
            end
        end

        function res = logic_dao_ham_func(app, f, x0, h, method)
            if strcmp(method, 'Xấp xỉ tiến'), res = (f(x0 + h) - f(x0)) / h;
            elseif strcmp(method, 'Lùi'), res = (f(x0) - f(x0 - h)) / h;
            else, res = (f(x0 + h) - f(x0 - h)) / (2*h);
            end
        end

        function result = logic_tich_phan(app, x, y, method)
            % Hàm tính tích phân chung cho cả dữ liệu và hàm số (sau khi đã đổi ra x,y)
            n_points = length(x);
            if n_points < 2, result = 0; return; end
            
            % Kiểm tra khoảng cách đều (cho Simpson)
            h_diff = diff(x);
            is_uniform = all(abs(h_diff - h_diff(1)) < 1e-9);
            h = mean(h_diff);
            
            if strcmp(method, 'Hình thang')
                result = trapz(x, y);
                
            elseif strcmp(method, 'Simpson 1/3')
                if ~is_uniform, error('Simpson 1/3 yêu cầu x cách đều nhau.'); end
                if mod(n_points - 1, 2) ~= 0, error('Simpson 1/3 yêu cầu số khoảng N chẵn (số điểm lẻ).'); end
                
                so_le = sum(y(2:2:end-1));
                so_chan = sum(y(3:2:end-2));
                result = (h/3) * (y(1) + 4*so_le + 2*so_chan + y(end));
                
            elseif strcmp(method, 'Simpson 3/8')
                if ~is_uniform, error('Simpson 3/8 yêu cầu x cách đều nhau.'); end
                if mod(n_points - 1, 3) ~= 0, error('Simpson 3/8 yêu cầu số khoảng N chia hết cho 3.'); end
                
                % Tính thủ công Simpson 3/8
                result = 0;
                for i = 1:3:(n_points-1)
                    result = result + (3*h/8) * (y(i) + 3*y(i+1) + 3*y(i+2) + y(i+3));
                end
            else
                result = 0;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ChoncachnhapListBox.Value = 'Nhập dữ liệu';
            app.Panelx_y.Visible = 'on';
            app.Panelhamso.Visible = 'off';
        end

        % Button pushed function: TimnghiemButton
        function TimnghiemButtonPushed(app, event)
           try
                % 1. Lấy Input
                str_func = app.NhapphuongtrinhEditField.Value;
                str_range = app.NhapkhoangphanlynghiemEditField.Value;
                tol = app.NhapsaischophepEditField.Value;
                method = app.ChonphuongphaptimListBox.Value;
                
                % 2. Chuẩn bị dữ liệu
                f = app.convert_string_to_function(str_func);
                range = str2num(str_range);
                
                if isempty(f) || length(range)<2
                    uialert(app.UIFigure, 'Lỗi nhập liệu!', 'Lỗi'); return;
                end
                
                % 3. Gọi hàm giải tìm nghiệm
                [root, iter] = app.logic_tim_nghiem(f, range(1), range(2), tol, method);
                
                % 4. Xuất Output
                app.KetquanghiemEditField.Value = root;
                app.SolanlapEditField.Value = iter;
                
                % 5. Vẽ đồ thị
                x_vals = linspace(range(1)-1, range(2)+1, 100);
                plot(app.UIAxes, x_vals, f(x_vals), 'b-');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, x_vals, zeros(size(x_vals)), 'k--');
                plot(app.UIAxes, root, f(root), 'ro', 'MarkerFaceColor', 'r');
                hold(app.UIAxes, 'off'); grid(app.UIAxes, 'on');
           catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
           end
        end

        % Button pushed function: NoisuyButton
        function NoisuyButtonPushed(app, event)
             try
                % 1. Lấy Input
                x = str2num(app.NhapdulieuxEditField.Value);
                y = str2num(app.NhapdulieuyEditField.Value);
                val = app.NhapgiatricannoisuyEditField.Value;
                method = app.ChonphuongphapnoisuyListBox.Value;
                
                if length(x) ~= length(y)
                    uialert(app.UIFigure, 'Lỗi kích thước X, Y', 'Lỗi'); return;
                end
                
                % 2. Gọi hàm giải nội suy
                [result, str_poly] = app.logic_noi_suy(x, y, val, method);
                
                % 3. Xuất Output
                app.KetquadathucnoisuyEditField.Value = str_poly;
                app.KetquanoisuyEditField.Value = result;
                
                % 4. Vẽ
                plot(app.UIAxes2, x, y, 'ko', 'MarkerFaceColor', 'k');
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2, val, result, 'rs', 'MarkerFaceColor', 'r');
                hold(app.UIAxes2, 'off'); grid(app.UIAxes2, 'on');
            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
             end
             
        end

        % Button pushed function: HoiquyButton
        function HoiquyButtonPushed(app, event)
             try
                % 1. Đọc dữ liệu thô
                x_raw = app.NhapdulieuxEditField_2.Value;
                y_raw = app.NhapdulieuyEditField_2.Value;
                
                % 2. Làm sạch
                x = app.clean_and_parse(x_raw);
                y = app.clean_and_parse(y_raw);
                val_predict = app.NhapgiatricandudoanEditField.Value;
                method = app.ChonphuongphaphoiquyListBox.Value;
                
                if length(x) ~= length(y)
                    uialert(app.UIFigure, sprintf('Độ dài X (%d) và Y (%d) không khớp', length(x), length(y)), 'Lỗi');
                    return;
                end
                
                [y_pred, eq_str, x_fit, y_fit] = app.logic_hoi_quy(x, y, val_predict, method);
                
                app.KetquaphuongtrinhhoiquyEditField.Value = eq_str;
                app.KetquadudoanEditField.Value = y_pred;
                
                cla(app.UIAxes3); % Xóa biểu đồ cũ
                plot(app.UIAxes3, x, y, 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 8); % Điểm dữ liệu
                hold(app.UIAxes3, 'on');
                plot(app.UIAxes3, x_fit, y_fit, 'b-', 'LineWidth', 1.5); % Đường hồi quy
                plot(app.UIAxes3, val_predict, y_pred, 'g*', 'MarkerSize', 12); % Điểm dự đoán
                hold(app.UIAxes3, 'off'); grid(app.UIAxes3, 'on');
                title(app.UIAxes3, ['Hồi quy: ' method]);
                
            catch ME
                uialert(app.UIFigure, ['Lỗi Hồi quy: ' ME.message], 'Lỗi');
             end
        end

        % Button pushed function: KetquaButton
        function KetquaButtonPushed(app, event)
            try
                % Chuyển đổi chuỗi nhập vào thành số (double)
                x0 = str2double(app.NhapgiatricantinhdaohamEditField_2.Value); 
                
                method = app.ChonphuongphapdaohamListBox.Value;
               
                mode = app.ChoncachnhapListBox.Value;
                res = NaN;
                if isnan(x0), uialert(app.UIFigure, 'Giá trị X không hợp lệ.', 'Lỗi'); return; end
                
                if strcmp(mode, 'Nhập hàm số')
                % TRƯỜNG HỢP 1: NHẬP HÀM SỐ
                    str_func = app.NhaphamsoEditField.Value;
                    f = app.convert_string_to_function(str_func);
            
            % Kiểm tra hàm có hợp lệ không
                    if isempty(f)
                        uialert(app.UIFigure, 'Vui lòng nhập hàm số hợp lệ.', 'Lỗi'); 
                        return; 
                    end
            % Lấy bước h
                    h = str2double(app.NhapbuochEditField.Value);
                    if isnan(h)
                        uialert(app.UIFigure, 'Vui lòng nhập bước h.', 'Lỗi'); 
                        return; 
                    end
            % Gọi hàm tính
                    res = app.logic_dao_ham_func(f, x0, h, method); 
                else 
            % TRƯỜNG HỢP 2: NHẬP DỮ LIỆU
                    x = app.clean_and_parse(app.NhapdulieuxEditField_3.Value);
                    y = app.clean_and_parse(app.NhapdulieuyEditField_3.Value);
            
                    if isempty(x) || isempty(y)
                        uialert(app.UIFigure, 'Vui lòng nhập đầy đủ dữ liệu X và Y.', 'Thiếu thông tin'); 
                        return;
                    end
            
                    if length(x) ~= length(y)
                        uialert(app.UIFigure, 'Kích thước mảng X và Y không bằng nhau.', 'Lỗi'); 
                        return;
                    end
            % Gọi hàm tính
                    res = app.logic_dao_ham_data(x, y, x0, method);
                end
        
        % 4. Xuất kết quả ra màn hình
                app.KetquaEditField.Limits = [-inf inf]; % Mở giới hạn hiển thị
                app.KetquaEditField.Value = res;
            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi Đạo hàm');
            end
        end

        % Button pushed function: KetquaButton_2
        function KetquaButton_2Pushed(app, event)
           try
               method = app.ChonphuongphaptichphanListBox.Value;
               mode = app.ChoncachnhapListBox_2.Value; % Lấy chế độ nhập
        
               x = []; y = [];
        
        % TRƯỜNG HỢP 1: NHẬP HÀM SỐ
               if strcmp(mode, 'Nhập hàm số')
                   str_func = app.NhaphamsoEditField_2.Value;
                   f = app.convert_string_to_function(str_func);
            
                   if isempty(f)
                       uialert(app.UIFigure, 'Hàm số không hợp lệ.', 'Lỗi'); 
                       return; 
                   end
            
                   limits = app.clean_and_parse(app.NhapcanEditField.Value);
                   N = app.NhapNEditField.Value;
            
                   if length(limits) < 2
                       uialert(app.UIFigure, 'Cận tích phân phải có 2 số [a b].', 'Lỗi'); 
                       return; 
                   end
            
                   if isempty(N) || N <= 0
                       uialert(app.UIFigure, 'N phải là số nguyên dương > 0.', 'Lỗi'); 
                       return; 
                   end
            
            % Tạo dữ liệu x, y từ hàm số
                   a = limits(1); b = limits(2);
            % linspace tạo ra N+1 điểm để có N khoảng chia
                   x = linspace(a, b, N + 1); 
                   y = f(x);
            
        % TRƯỜNG HỢP 2: NHẬP DỮ LIỆU
               else 
                   x = app.clean_and_parse(app.NhapdulieuxEditField_4.Value);
                   y = app.clean_and_parse(app.NhapdulieuyEditField_4.Value);
            
                   if isempty(x) || isempty(y)
                       uialert(app.UIFigure, 'Vui lòng nhập đầy đủ dữ liệu X và Y.', 'Thiếu thông tin'); 
                       return; 
                   end
                   if length(x) ~= length(y)
                       uialert(app.UIFigure, 'Kích thước mảng X và Y lệch nhau.', 'Lỗi'); 
                       return; 
                   end
               end
        
        % Gọi logic tính tích phân (chung cho cả 2 trường hợp sau khi đã có x, y)
               result = app.logic_tich_phan(x, y, method);
        
               app.Ketqua_1EditField.Limits = [-inf inf];
               app.Ketqua_1EditField.Value = result;
        
           catch ME
               uialert(app.UIFigure, ME.message, 'Lỗi Tích phân');
           end
        end

        % Value changed function: ChonphuongphaptimListBox
        function ChonphuongphaptimListBoxValueChanged(app, event)
            value = app.ChonphuongphaptimListBox.Value;
            if strcmp(value, 'Lặp')
                app.NhpphngtrnhEditFieldLabel.Text = 'Nhâp hàm lặp g(x)';
                app.NhpphngtrnhEditFieldLabel.FontColor = [1 0 0];
                uialert(app.UIFigure, ['Với phương pháp Lặp đơn, bạn cần biến đổi phương trình f(x) = 0 về dạng x = g(x), sau đó chỉ nhập vế phải g(x) vào ô.'], 'Hướng dẫn nhập liệu', 'Icon','info');
            else
                app.NhpphngtrnhEditFieldLabel.Text = 'Nhâp phương trình f(x)';
                app.NhpphngtrnhEditFieldLabel.FontColor = [0 0 0];
            end
        end

        % Value changed function: ChoncachnhapListBox
        function ChoncachnhapListBoxValueChanged(app, event)
            value = app.ChoncachnhapListBox.Value;
            if strcmp(value, 'Nhập hàm số')
                app.Panelhamso.Visible = 'on';
                app.Panelx_y.Visible = 'off';
            else
                app.Panelhamso.Visible = 'off';
                app.Panelx_y.Visible = 'on';
            end
        end

        % Value changed function: ChoncachnhapListBox_2
        function ChoncachnhapListBox_2ValueChanged(app, event)
            value = app.ChoncachnhapListBox_2.Value;
            if strcmp(value, 'Nhập hàm số')
                app.Panelhamso_2.Visible = 'on';
                app.Panelx_y_2.Visible = 'off';
            else
                app.Panelhamso_2.Visible = 'off';
                app.Panelx_y_2.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.NhapsaischophepEditField = uieditfield(app.NghiemTab, 'numeric');
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhapphuongtrinhEditField = uieditfield(app.NghiemTab, 'text');
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm';
            app.NhapkhoangphanlynghiemEditField = uieditfield(app.NghiemTab, 'text');
            app.KtqunghimEditFieldLabel = uilabel(app.NghiemTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KetquanghiemEditField = uieditfield(app.NghiemTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SolanlapEditField = uieditfield(app.NghiemTab, 'numeric');
            app.TimnghiemButton = uibutton(app.NghiemTab, 'push');
            app.TimnghiemButton.ButtonPushedFcn = createCallbackFcn(app, @TimnghiemButtonPushed, true);
            app.TimnghiemButton.Icon = fullfile(pathToMLAPP, 'search.png');
            app.TimnghiemButton.Text = 'Tìm nghiệm';
            app.ChnphngphptmListBoxLabel = uilabel(app.NghiemTab);
            app.ChnphngphptmListBoxLabel.Text = 'Chọn phương pháp tìm';
            app.ChonphuongphaptimListBox = uilistbox(app.NghiemTab);
            app.ChonphuongphaptimListBox.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)'};
            app.ChonphuongphaptimListBox.ValueChangedFcn = createCallbackFcn(app, @ChonphuongphaptimListBoxValueChanged, true);
            app.ChonphuongphaptimListBox.Value = 'Chia đôi';
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NoisuyTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.NoisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhapdulieuxEditField = uieditfield(app.NoisuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NoisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhapdulieuyEditField = uieditfield(app.NoisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NoisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhapgiatricannoisuyEditField = uieditfield(app.NoisuyTab, 'numeric');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NoisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KetquadathucnoisuyEditField = uieditfield(app.NoisuyTab, 'text');
            app.KtqunisuyEditFieldLabel = uilabel(app.NoisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KetquanoisuyEditField = uieditfield(app.NoisuyTab, 'numeric');
            app.ChnphngphpnisuyListBoxLabel = uilabel(app.NoisuyTab);
            app.ChnphngphpnisuyListBoxLabel.Text = 'Chọn phương pháp nội suy';
            app.ChonphuongphapnoisuyListBox = uilistbox(app.NoisuyTab);
            app.ChonphuongphapnoisuyListBox.Items = {'Newton', 'Lagrange'};
            app.ChonphuongphapnoisuyListBox.Value = 'Newton';
            app.NoisuyButton = uibutton(app.NoisuyTab, 'push');
            app.NoisuyButton.ButtonPushedFcn = createCallbackFcn(app, @NoisuyButtonPushed, true);
            app.NoisuyButton.Text = 'Nội suy';
            app.HoiQuyTab = uitab(app.TabGroup);
            app.HoiQuyTab.Title = 'Hồi Quy';
            app.UIAxes3 = uiaxes(app.HoiQuyTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.NhpdliuxEditField_2Label = uilabel(app.HoiQuyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x';
            app.NhapdulieuxEditField_2 = uieditfield(app.HoiQuyTab, 'text');
            app.NhpdliuyEditField_2Label = uilabel(app.HoiQuyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y';
            app.NhapdulieuyEditField_2 = uieditfield(app.HoiQuyTab, 'text');
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HoiQuyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.KetquaphuongtrinhhoiquyEditField = uieditfield(app.HoiQuyTab, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HoiQuyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhapgiatricandudoanEditField = uieditfield(app.HoiQuyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HoiQuyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KetquadudoanEditField = uieditfield(app.HoiQuyTab, 'numeric');
            app.ChnphngphphiquyListBoxLabel = uilabel(app.HoiQuyTab);
            app.ChnphngphphiquyListBoxLabel.Text = 'Chọn phương pháp hồi quy';
            app.ChonphuongphaphoiquyListBox = uilistbox(app.HoiQuyTab);
            app.ChonphuongphaphoiquyListBox.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChonphuongphaphoiquyListBox.Value = 'Tuyến tính';
            app.HoiquyButton = uibutton(app.HoiQuyTab, 'push');
            app.HoiquyButton.ButtonPushedFcn = createCallbackFcn(app, @HoiquyButtonPushed, true);
            app.HoiquyButton.Text = 'Hồi quy';
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo hàm';
            app.ChngitrsaisListBoxLabel = uilabel(app.DaohamTab);
            app.ChngitrsaisListBoxLabel.Text = 'Chọn giá trị sai số';
            app.ChongiatrisaisoListBox = uilistbox(app.DaohamTab);
            app.ChongiatrisaisoListBox.Items = {'O(h)', 'O(h^2)'};
            app.ChongiatrisaisoListBox.Value = 'O(h)';
            app.ChnphngphpohmListBoxLabel = uilabel(app.DaohamTab);
            app.ChnphngphpohmListBoxLabel.Text = 'Chọn phương pháp đạo hàm';
            app.ChonphuongphapdaohamListBox = uilistbox(app.DaohamTab);
            app.ChonphuongphapdaohamListBox.Items = {'Xấp xỉ tiến', 'Lùi', 'Trung tâm'};
            app.ChonphuongphapdaohamListBox.Value = 'Xấp xỉ tiến';
            app.KetquaButton = uibutton(app.DaohamTab, 'push');
            app.KetquaButton.ButtonPushedFcn = createCallbackFcn(app, @KetquaButtonPushed, true);
            app.KetquaButton.Text = 'Kết quả';
            app.KetquaEditField = uieditfield(app.DaohamTab, 'numeric');
            app.NhpgitrcntnhohmEditField_2Label = uilabel(app.DaohamTab);
            app.NhpgitrcntnhohmEditField_2Label.Text = 'Nhập giá trị cần tính đạo hàm';
            app.NhapgiatricantinhdaohamEditField_2 = uieditfield(app.DaohamTab, 'text');
            app.ChncchnhpListBoxLabel = uilabel(app.DaohamTab);
            app.ChncchnhpListBoxLabel.Text = 'Chọn cách nhập';
            app.ChoncachnhapListBox = uilistbox(app.DaohamTab);
            app.ChoncachnhapListBox.Items = {'Nhập dữ liệu', 'Nhập hàm số'};
            app.ChoncachnhapListBox.ValueChangedFcn = createCallbackFcn(app, @ChoncachnhapListBoxValueChanged, true);
            app.ChoncachnhapListBox.Value = 'Nhập dữ liệu';
            app.Panelx_y = uipanel(app.DaohamTab);
            app.NhpdliuxEditField_3Label = uilabel(app.Panelx_y);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x';
            app.NhapdulieuxEditField_3 = uieditfield(app.Panelx_y, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.Panelx_y);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y';
            app.NhapdulieuyEditField_3 = uieditfield(app.Panelx_y, 'text');
            app.Panelhamso = uipanel(app.DaohamTab);
            app.NhphmsEditFieldLabel = uilabel(app.Panelhamso);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.NhaphamsoEditField = uieditfield(app.Panelhamso, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.Panelhamso);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.NhapbuochEditField = uieditfield(app.Panelhamso, 'text');
            app.TichphanTab = uitab(app.TabGroup);
            app.TichphanTab.Title = 'Tích phân';
            app.ChnphngphptchphnListBoxLabel = uilabel(app.TichphanTab);
            app.ChnphngphptchphnListBoxLabel.Text = 'Chọn phương pháp tích phân';
            app.ChonphuongphaptichphanListBox = uilistbox(app.TichphanTab);
            app.ChonphuongphaptichphanListBox.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChonphuongphaptichphanListBox.Value = 'Hình thang';
            app.NhpNEditFieldLabel = uilabel(app.TichphanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhapNEditField = uieditfield(app.TichphanTab, 'numeric');
            app.KetquaButton_2 = uibutton(app.TichphanTab, 'push');
            app.KetquaButton_2.ButtonPushedFcn = createCallbackFcn(app, @KetquaButton_2Pushed, true);
            app.KetquaButton_2.Text = 'Kết quả';
            app.Ketqua_1EditField = uieditfield(app.TichphanTab, 'numeric');
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TichphanTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân';
            app.NhapcanEditField = uieditfield(app.TichphanTab, 'text');
            app.Panelhamso_2 = uipanel(app.TichphanTab);
            app.NhphmsEditField_2Label = uilabel(app.Panelhamso_2);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số';
            app.NhaphamsoEditField_2 = uieditfield(app.Panelhamso_2, 'text');
            app.ChncchnhpListBoxLabel_2 = uilabel(app.TichphanTab);
            app.ChncchnhpListBoxLabel_2.Text = 'Chọn cách nhập';
            app.ChoncachnhapListBox_2 = uilistbox(app.TichphanTab);
            app.ChoncachnhapListBox_2.Items = {'Nhập dữ liệu', 'Nhập hàm số'};
            app.ChoncachnhapListBox_2.ValueChangedFcn = createCallbackFcn(app, @ChoncachnhapListBox_2ValueChanged, true);
            app.ChoncachnhapListBox_2.Value = 'Nhập dữ liệu';
            app.Panelx_y_2 = uipanel(app.TichphanTab);
            app.NhpdliuxEditField_4Label = uilabel(app.Panelx_y_2);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x';
            app.NhapdulieuxEditField_4 = uieditfield(app.Panelx_y_2, 'text');
            app.NhpdliuyEditField_4Label = uilabel(app.Panelx_y_2);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y';
            app.NhapdulieuyEditField_4 = uieditfield(app.Panelx_y_2, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.Image = uiimage(app.GiithiunhmTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'z7246115938870_6af4d794ee309f0ce10c6c6a799b997f.jpg');
            app.GVHDThsHunhQucThnhLabel = uilabel(app.GiithiunhmTab);
            app.GVHDThsHunhQucThnhLabel.Text = 'GVHD: Ths Huỳnh Quốc Thịnh';
            app.Lp23DTV_CLC2Label = uilabel(app.GiithiunhmTab);
            app.Lp23DTV_CLC2Label.Text = 'Lớp: 23DTV_CLC2';
            app.PhmMinhNgcLabel = uilabel(app.GiithiunhmTab);
            app.PhmMinhNgcLabel.Text = '1. Phạm Minh Ngọc';
            app.MSSV23207012Label = uilabel(app.GiithiunhmTab);
            app.MSSV23207012Label.Text = 'MSSV: 23207012';
            app.VaitrsonwordLabel = uilabel(app.GiithiunhmTab);
            app.VaitrsonwordLabel.Text = 'Vai trò: soạn word';
            app.LNguynThyDngLabel = uilabel(app.GiithiunhmTab);
            app.LNguynThyDngLabel.Text = '2. Lê Nguyễn Thùy Dương';
            app.MSSV23207023Label = uilabel(app.GiithiunhmTab);
            app.MSSV23207023Label.Text = 'MSSV: 23207023';
            app.VaitrthitlpgiaodinchnhwordLabel = uilabel(app.GiithiunhmTab);
            app.VaitrthitlpgiaodinchnhwordLabel.Text = 'Vai trò: thiết lập giao diện, chỉnh word';
            app.VcQuangLabel = uilabel(app.GiithiunhmTab);
            app.VcQuangLabel.Text = '3. Vũ Đức Quang';
            app.MSSV23207017Label = uilabel(app.GiithiunhmTab);
            app.MSSV23207017Label.Text = 'MSSV: 23207017';
            app.VaitrcodeLabel = uilabel(app.GiithiunhmTab);
            app.VaitrcodeLabel.Text = 'Vai trò: code';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo-KHTN.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'logo-khoa.jpg');
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'ĐỒ ÁN';
            app.THCHNHPHNGPHPTNHLabel = uilabel(app.UIFigure);
            app.THCHNHPHNGPHPTNHLabel.Text = 'THỰC HÀNH PHƯƠNG PHÁP TÍNH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Do_an_PPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end