classdef lab2_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        VButton              matlab.ui.control.Button
        WEditField           matlab.ui.control.NumericEditField
        WEditFieldLabel      matlab.ui.control.Label
        lamdaEditField       matlab.ui.control.NumericEditField
        lamdaEditFieldLabel  matlab.ui.control.Label
        VtEditField          matlab.ui.control.NumericEditField
        VtEditFieldLabel     matlab.ui.control.Label
        unCoxEditField       matlab.ui.control.NumericEditField
        unCoxEditFieldLabel  matlab.ui.control.Label
        LEditField           matlab.ui.control.NumericEditField
        LEditFieldLabel      matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            VGS_values = [0.5 2 3.5 4.5];
            unCox = app.unCoxEditField.Value;
            Vt = app.VtEditField.Value;
            lamda = app.lamdaEditField.Value;
            W = app.WEditField.Value;
            L = app.LEditField.Value;
            VDS = 0:0.1:5;
            hold(app.UIAxes, 'on');
            for VGS = VGS_values
                IDS = zeros(size(VDS));
                for k = 1:length(VDS)
                    if (VGS <= Vt)
                        IDS(k) = 0;
                    elseif (VGS>Vt && VDS(k)<=(VGS - Vt))
                        IDS(k) = unCox*(W/L)*((VGS - Vt)*VDS(k) - (VDS(k)^2)/2)*(1+ lamda*VDS(k));
                    else
                        IDS(k) = unCox*(W/L)*(VGS - Vt)^2*(1+ lamda*VDS(k));
                    end
                end
                plot(app.UIAxes, VDS, IDS, 'DisplayName', sprintf('V_{GS} = %.1f V', VGS));
            end
            xlabel(app.UIAxes, 'VDS' );
            ylabel(app.UIAxes, 'IDS');
            title(app.UIAxes, 'Đặc tính của MOSFET');
            hold(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.unCoxEditFieldLabel = uilabel(app.UIFigure);
            app.unCoxEditFieldLabel.Text = 'unCox';
            app.unCoxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VtEditFieldLabel = uilabel(app.UIFigure);
            app.VtEditFieldLabel.Text = 'Vt';
            app.VtEditField = uieditfield(app.UIFigure, 'numeric');
            app.lamdaEditFieldLabel = uilabel(app.UIFigure);
            app.lamdaEditFieldLabel.Text = 'lamda';
            app.lamdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.WEditFieldLabel = uilabel(app.UIFigure);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.UIFigure, 'numeric');
            app.VButton = uibutton(app.UIFigure, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'Vẽ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab2_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end